classdef MicroplateGR2 < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure                      matlab.ui.Figure
      Menu                          matlab.ui.container.Menu
      OpenMPDataFileMenu            matlab.ui.container.Menu
      SaveGrowthParameterstoSpreadsheetMenu  matlab.ui.container.Menu
      ExportSelectedGrowthCurveODDataMenu  matlab.ui.container.Menu
      ClearAllDataMenu              matlab.ui.container.Menu
      ExitMenu                      matlab.ui.container.Menu
      SettingsMenu                  matlab.ui.container.Menu
      AutosetButton                 matlab.ui.control.Button
      ColumnLabel                   matlab.ui.control.Label
      Knob                          matlab.ui.control.DiscreteKnob
      ControlODstdevEditField       matlab.ui.control.NumericEditField
      stdevLabel                    matlab.ui.control.Label
      OutputFileNameEditField       matlab.ui.control.EditField
      OutputFileNameEditFieldLabel  matlab.ui.control.Label
      ControlRowsDropDown_end       matlab.ui.control.DropDown
      Label                         matlab.ui.control.Label
      ColumnResetButton             matlab.ui.control.Button
      LagTimeEditField              matlab.ui.control.NumericEditField
      LagTimeEditFieldLabel         matlab.ui.control.Label
      ControlODEditField            matlab.ui.control.NumericEditField
      ControlODEditFieldLabel       matlab.ui.control.Label
      DataSetEditField              matlab.ui.control.EditField
      DataSetNameLabel              matlab.ui.control.Label
      Row                           matlab.ui.container.ButtonGroup
      EditField_1                   matlab.ui.control.NumericEditField
      EditField_2                   matlab.ui.control.NumericEditField
      EditField_3                   matlab.ui.control.NumericEditField
      EditField_4                   matlab.ui.control.NumericEditField
      EditField_5                   matlab.ui.control.NumericEditField
      EditField_6                   matlab.ui.control.NumericEditField
      EditField_7                   matlab.ui.control.NumericEditField
      EditField_8                   matlab.ui.control.NumericEditField
      RowLabel                      matlab.ui.control.Label
      InitODCtlODLabel              matlab.ui.control.Label
      AButton                       matlab.ui.control.ToggleButton
      BButton                       matlab.ui.control.ToggleButton
      CButton                       matlab.ui.control.ToggleButton
      DButton                       matlab.ui.control.ToggleButton
      EButton                       matlab.ui.control.ToggleButton
      FButton                       matlab.ui.control.ToggleButton
      GButton                       matlab.ui.control.ToggleButton
      HButton                       matlab.ui.control.ToggleButton
      Col_1                         matlab.ui.control.StateButton
      Col_2                         matlab.ui.control.StateButton
      Col_3                         matlab.ui.control.StateButton
      Col_4                         matlab.ui.control.StateButton
      Col_5                         matlab.ui.control.StateButton
      Col_6                         matlab.ui.control.StateButton
      Col_7                         matlab.ui.control.StateButton
      Col_8                         matlab.ui.control.StateButton
      Col_9                         matlab.ui.control.StateButton
      Col_10                        matlab.ui.control.StateButton
      Col_11                        matlab.ui.control.StateButton
      Col_12                        matlab.ui.control.StateButton
      SavetoOutputFileButton        matlab.ui.control.Button
      DataFileNameEditField         matlab.ui.control.EditField
      DataFileNameEditFieldLabel    matlab.ui.control.Label
      MaxLnODEditField              matlab.ui.control.NumericEditField
      MaxLnODEditFieldLabel         matlab.ui.control.Label
      DoublingTimeEditField         matlab.ui.control.NumericEditField
      DoublingTimeEditFieldLabel    matlab.ui.control.Label
      GrowthRateEditField           matlab.ui.control.NumericEditField
      GrowthRateEditFieldLabel      matlab.ui.control.Label
      GrowthKineticsLabel           matlab.ui.control.Label
      ControlRowsDropDown_start     matlab.ui.control.DropDown
      ControlRowsDropDownLabel      matlab.ui.control.Label
      ControlColDropDown            matlab.ui.control.DropDown
      ControlColDropDownLabel       matlab.ui.control.Label
      UIAxes                        matlab.ui.control.UIAxes
   end

   properties (Access = private)
      M_MP           %MPlate class model
      ProcessbyN     %sequential processing by N data points
      GraphLn        %type of growth curve graph
      minODBkg       %minimum OD above background
      firstN         %min OD dataset
      plottype       %type of data to plot
      paramoutfilepath %path for output of GR parameters
   end %function
   
   methods (Access = private)
      
      function ClearAll(app)
         app.ClearResults();     %zero all results display
         app.M_MP.closegraph();  %before deleting object
         delete(app.M_MP);       %handle class, now "invalid", but not del
         app.DataFileNameEditField.Value = ""; %clear datafile name
         app.OutputFileNameEditField.Value = ""; %clear filename field
         app.ControlColDropDown.Value = "12"; %reset control for blank
         app.ControlRowsDropDown_start.Value = 'A'; %blank row start
         app.ControlRowsDropDown_end.Value = 'H'; %blank row end
         app.ControlODEditField.Value = 0; %contol OD
         app.ControlODstdevEditField.Value = 0;
         app.ProcessbyN = 4;     %default sequential processing val
         app.GraphLn = 'Y';      %graph data as Ln values
         app.firstN = 5;         %fist data points for min OD
         app.paramoutfilepath = ""; %empty path
         app.plottype = 'Growth Curves'; %reset plot type
         app.Knob.Value = 'Growth Curves'; %reset knobclc
      end %function
         
      function ClearResults(app)
         app.GrowthRateEditField.Value = 0;
         app.DoublingTimeEditField.Value = 0;
         app.MaxLnODEditField.Value = 0;
         % app.InitialODEditField.Value = 0;
         app.LagTimeEditField.Value = 0;
         app.Col_1.Value = 0;
         app.Col_2.Value = 0;
         app.Col_3.Value = 0;
         app.Col_4.Value = 0;
         app.Col_5.Value = 0;
         app.Col_6.Value = 0;
         app.Col_7.Value = 0;
         app.Col_8.Value = 0;
         app.Col_9.Value = 0;
         app.Col_10.Value = 0;
         app.Col_11.Value = 0;
         app.Col_12.Value = 0;
         app.EditField_1.Value = 0;
         app.EditField_2.Value = 0;
         app.EditField_3.Value = 0;
         app.EditField_4.Value = 0;
         app.EditField_5.Value = 0;
         app.EditField_6.Value = 0;
         app.EditField_7.Value = 0;
         app.EditField_8.Value = 0;
         cla(app.UIAxes, 'reset');
      end %function

      function colvec = getCols(app)
         colvec = zeros(1,12);
         colvec(1) = app.Col_1.Value();
         colvec(2) = app.Col_2.Value();
         colvec(3) = app.Col_3.Value();
         colvec(4) = app.Col_4.Value();
         colvec(5) = app.Col_5.Value();
         colvec(6) = app.Col_6.Value();
         colvec(7) = app.Col_7.Value();
         colvec(8) = app.Col_8.Value();
         colvec(9) = app.Col_9.Value();
         colvec(10) = app.Col_10.Value();
         colvec(11) = app.Col_11.Value();
         colvec(12) = app.Col_12.Value();
      end %

      function rowval = getRow(~,letter)
         % letter = app.Row.SelectedObject;
         % row = letter.Text;
         switch letter
            case 'A'
               rowval = 1;
            case 'B'
               rowval = 2;
            case 'C'
               rowval = 3;
            case 'D'
               rowval = 4;
            case 'E'
               rowval = 5;
            case 'F'
               rowval = 6;
            case 'G'
               rowval = 7;
            case 'H'
               rowval = 8;
            otherwise
               rowval = 1;
         end %switch
      end %function

      function rowcols = getRowCols(app)
         coldata = app.getCols();
         ncols = sum(coldata);
         colvec = zeros(1,ncols);
         colindex = 1;
         for i=1:12
            if coldata(i) == 1
               colvec(colindex) = i;
               colindex = colindex + 1;
            end %if
         end %for
         letter = app.Row.SelectedObject.Text;
         rowcols.row = app.getRow(letter);
         rowcols.colvec = colvec;
      end %function
% 
      function ODval = setControlOD(app)
         ODval = 0;                             %set OD if empty M_MP
         if isvalid(app.M_MP)                  %check for valid M_MP
            startletter = app.ControlRowsDropDown_start.Value; %letter
            endletter = app.ControlRowsDropDown_end.Value; %letter
            startval = app.getRow(startletter); %number
            endval = app.getRow(endletter);     %number
            coltext = app.ControlColDropDown.Value; %text number
            colval = str2double(coltext);       %col number
            ODdata = app.M_MP.getBlankData(startval:endval, colval); %data
            app.ControlODEditField.Value = ODdata(1); % 1st val = OD
            app.ControlODstdevEditField.Value = ODdata(2); % 2nd = stdev
         end
      end %function
      
      function indx = getSelectedIndex(app, MinODs)
         %return first row minOD val lower than app.minODBkg
         indx = 1;
         for i = 1:8
            if MinODs(i) < app.minODBkg
               indx = i;
               break;
            end
         end
      end
      
      function setRowButton(app, selindex)
         switch selindex 
            case 1
               app.AButton.Value = 1;
            case 2
               app.BButton.Value = 1;
            case 3
               app.CButton.Value = 1;
            case 4
               app.DButton.Value = 1;
            case 5
               app.EButton.Value = 1;
            case 6
               app.FButton.Value = 1;
            case 7 
               app.GButton.Value = 1;
            case 8
               app.HButton.Value = 1;
         end
      end

      function postRowInitOD(app,procparams)
         MinODs = zeros(8,1);
         for i=1:8
            tempdata = app.M_MP.getCurveData(i,procparams.colvec, ...
               procparams.blank);
            MinODs(i) = tempdata.meanfirstNMinOD;
         end %for
         %set selected index
         % selindex = app.getSelectedIndex(MinODs);
         % app.setRowButton(selindex);
         %post values 
         app.EditField_1.Value = MinODs(1);
         app.EditField_2.Value = MinODs(2);
         app.EditField_3.Value = MinODs(3);
         app.EditField_4.Value = MinODs(4);
         app.EditField_5.Value = MinODs(5);
         app.EditField_6.Value = MinODs(6);
         app.EditField_7.Value = MinODs(7);
         app.EditField_8.Value = MinODs(8);
      end %function

      function procparams = getProcParams(app)
         procparams.bool = false;
         rowcol = app.getRowCols();
         if ~isempty(rowcol.colvec) %Don't process if no cols
            if isvalid(app.M_MP)
               procparams.row = rowcol.row;    %set row
               procparams.colvec = rowcol.colvec; %set col
               procparams.byN = app.ProcessbyN;  %set procbyN
               procparams.blank = app.ControlODEditField.Value; %set blank
               procparams.bool = true;            %bool = true
            end %if
         end %if
      end %function

      function plotCurves(app, procparams)
         %get curve datafor plotting
         pd = procparams;
         if pd.bool
            crvdata = app.M_MP.getCurveData(pd.row,pd.colvec,pd.blank);
            modelcrv = app.M_MP.getGompertz(crvdata, ...
               app.GrowthRateEditField.Value, app.LagTimeEditField.Value);
            cla(app.UIAxes, 'reset');
            hold(app.UIAxes,"on");
            if app.GraphLn == 'Y' 
               for i=1:crvdata.NYcols                  %plot each curve
                  plot(app.UIAxes,crvdata.Xcol,crvdata.LnYcols(:,i),'ok');  
               end %for
               plot(app.UIAxes,modelcrv(:,1),modelcrv(:,2),'-k'); %model
               ylabel(app.UIAxes,"Ln Optical Density (600 nm)"); %Y axis label
            else
               for i=1:crvdata.NYcols                  %plot each curve
                   plot(app.UIAxes,crvdata.Xcol,crvdata.Ycols(:,i),'ok');  
               end %for 
               plot(app.UIAxes,crvdata.Xcol,crvdata.meanYcol,'-k');
               ylabel(app.UIAxes,"Optical Density (600 nm)"); %Y axis label
               title(app.UIAxes,'Mean OD');
            end %if
            xlabel(app.UIAxes,"Time (h)");              %X axis label  
            hold(app.UIAxes,"off");                     %end hold
         end %if
      end %function

      function plotRates(app,pd)
         if pd.bool
            repTable = app.M_MP.getRepTable(pd.colvec,pd.blank,pd.byN);
            cla(app.UIAxes, 'reset');
            hold(app.UIAxes,"on");
            yvals=repTable.GR;
            errorbar(app.UIAxes,repTable.PlateRow, yvals, ...
               repTable.StdErrGR,'-ok');
            xlim(app.UIAxes,[1,8]);
            ylim(app.UIAxes,[0,inf]);
            xlabel(app.UIAxes, "Row");
            xticklabels(app.UIAxes, {'A','B','C','D','E','F','G','H'});
            ylabel(app.UIAxes, "Growth Rate (per time unit)");
            plot(app.UIAxes,pd.row,repTable.GR(pd.row),'.r','MarkerSize',15);     
            hold(app.UIAxes,"off");                            %end hold
         end %if
      end %function

      function plotInitOD(app,pd)
         if pd.bool
            repTable = app.M_MP.getRepTable(pd.colvec,pd.blank,pd.byN);
            cla(app.UIAxes, 'reset');
            hold(app.UIAxes,"on");
            yvals = repTable.MinOD;
            % yvals(yvals<-6.21) = nan;  %less than 0.002 OD
            Ylen = length(yvals);
            for i=1:Ylen
               if repTable.BlankError(i) == 1
                  yvals(i) = nan;
               end %if
            end %for
            errorbar(app.UIAxes,repTable.PlateRow, yvals, ...
               repTable.StdErrMinOD,'-ok');
            xlim(app.UIAxes,[1,8]);
            xlabel(app.UIAxes, "Row");
            xticklabels(app.UIAxes, {'A','B','C','D','E','F','G','H'});
            ylabel(app.UIAxes, "Initial Ln OD");
            plot(app.UIAxes,pd.row,repTable.MinOD(pd.row), ...
               '.r','MarkerSize',15);     
            hold(app.UIAxes,"off");                            %end hold
         end %if
      end %function

      function plotMaxOD(app,pd)
         if pd.bool
            repTable = app.M_MP.getRepTable(pd.colvec,pd.blank,pd.byN);
            cla(app.UIAxes, 'reset');
            hold(app.UIAxes,"on");
            yvals = repTable.MaxOD;
            yvals(yvals==0)=nan;
            errorbar(app.UIAxes,repTable.PlateRow, yvals, ...
               repTable.StdErrMaxOD,'-ok');
            xlim(app.UIAxes,[1,8]);
            xlabel(app.UIAxes, "Row");
            xticklabels(app.UIAxes, {'A','B','C','D','E','F','G','H'});
            ylabel(app.UIAxes, "Maximum OD");
            plot(app.UIAxes,pd.row,repTable.MaxOD(pd.row), ...
               '.r','MarkerSize',15);     
            hold(app.UIAxes,"off");                            %end hold
         end %if
      end %function

      function plotLagTime(app,pd)
         if pd.bool
            repTable = app.M_MP.getRepTable(pd.colvec,pd.blank,pd.byN);
            cla(app.UIAxes, 'reset');
            hold(app.UIAxes,"on");
            yvals = repTable.LagTime;
            yvals(yvals==0)=nan;
            errorbar(app.UIAxes,repTable.PlateRow, yvals, ...
               repTable.StdErrLagTime,'-ok');
            xlim(app.UIAxes,[1,8]);
            ylim(app.UIAxes,[0,inf]);
            xlabel(app.UIAxes, "Row");
            xticklabels(app.UIAxes, {'A','B','C','D','E','F','G','H'});
            ylabel(app.UIAxes, "Lag Time (time unit)");
            plot(app.UIAxes,pd.row,repTable.LagTime(pd.row), ...
               '.r','MarkerSize',15);     
            hold(app.UIAxes,"off");                            %end hold
         end %if
      end %function

      function ProcessData(app)
         pd = app.getProcParams();
         if pd.bool && isvalid(app.M_MP)
            %post initial OD values in OD numbers not Ln numbers
            app.postRowInitOD(pd);
            %get growth kinetics
            Rtab = app.M_MP.getRepTable(pd.colvec,pd.blank,pd.byN);
            %Report GR data values
            % Check for IsvalidCt: if Rtab{pd.row,'InvalidCt'} == 1 
            app.MaxLnODEditField.Value = Rtab{pd.row,'MaxOD'}; 
            app.LagTimeEditField.Value = Rtab{pd.row,'LagTime'}; 
            app.GrowthRateEditField.Value = Rtab{pd.row,'GR'};
            app.DoublingTimeEditField.Value = Rtab{pd.row,'DblTime'};
            cols = pd.colvec;
            colstring = string(mat2str(cols));
            datasetname = strcat('Columns: ',colstring);
            app.DataSetEditField.Value = datasetname;
            %plot curves
            switch app.plottype
               case 'Growth Curves'
                  app.plotCurves(pd)
               case 'Growth Rates'
                  app.plotRates(pd);
               case 'Init OD'
                  app.plotInitOD(pd);
               case  'Max OD'
                  app.plotMaxOD(pd);
               case  'Lag Time'
                  app.plotLagTime(pd);
            end %switch
         else
            app.ClearResults();
         end %if
      end %function

      function results = checkblankrange(app)
         lettervec = ['A','B','C','D','E','F','G','H'];
         startval = app.ControlRowsDropDown_start.Value;
         endval = app.ControlRowsDropDown_end.Value;
         startindex = app.getRow(startval);
         endindex = app.getRow(endval);
         if startindex <= endindex
            results = true;
         else
            app.ControlRowsDropDown_start.Value = lettervec(endindex);
            results = false;
         end % if 
         if ~isvalid(app.M_MP)
            results = false;
         end %if
      end

       end %methods
   

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app)
         app.M_MP = MPmodel2.empty;
         app.ProcessbyN = 4;     %default sequential processing val
         app.GraphLn = 'Y';      %graph data as Ln values
         app.minODBkg = 0.05;    %minimum OD value above background
         app.firstN = 5;         %fist data points for min OD
         app.plottype = 'Growth Curves';  %type of data to plot
         app.paramoutfilepath = "";  %empty struct
      end

      % Menu selected function: OpenMPDataFileMenu
      function OpenMPDataFileMenuSelected(app, event)
         %open a spreadsheet with microplate data
         %12xN (Cols x hours)
         [filename, filepath] = uigetfile({'*.csv';'*.*'},...
            "Open .csv data file"); 
         if filename == 0
               return      
         else %not cancel
            try
                file = fullfile(filepath, filename);  %assembles full path
                cd(filepath);
                plate_XYY = readtable(file);
                [~,name,ext] = fileparts(file);
                displayname = strcat(name,ext);
                app.M_MP = MPmodel2(plate_XYY,displayname);
                app.DataFileNameEditField.Value = app.M_MP.getDataName();
                app.setControlOD();
                app.ProcessData();
                app.M_MP.showplate();

            catch
                errordlg('please check the data file format', ...
                    'File format error');
                app.ClearAll();
            end
         end %if
      end

      % Menu selected function: SettingsMenu
      function SettingsMenuSelected(app, event)
         %use anonymous function to jury input from dialog
         isgoodbyN = @(x)isnumeric(x) & x==floor(x) & x > 0 & x < 11;
         isgoodYNchar = @(x)ischar(x) & (x=='N' | x=='Y' | ...
            x=='y' | x=='n');
         isgoodminOD = @(x)isnumeric(x) & x > 0 & x < 1;
         %set dialog box
         prompt = {'Process-by-N value (integer, 0..11):', ...
                   'Plot log of Growth (Y/N):', ...
                   'Min OD above background value (decimal, 0..1):'};
         dlgtitle = '';
         fieldsize = [1 30;1 30;1 30];
         definput = cellstr({num2str(app.ProcessbyN),...
            app.GraphLn,num2str(app.minODBkg)});
         %get answer (cell array)
         answer = inputdlg(prompt,dlgtitle,fieldsize,definput);
         if ~isempty(answer) %user did not cancel
            numberval = str2double(answer{1,1}); %number for process by N
            if isgoodbyN(numberval)
               app.ProcessbyN = numberval;
            end
            charval  = answer{2,1}; %Char for graph type
            if isgoodYNchar(charval) 
               app.GraphLn = upper(charval); % use upper case for y,n
            end
            numberval = str2double(answer{3,1});
            if isgoodminOD(numberval)
               app.minODBkg = numberval;
            end
            app.AutosetButtonPushed() %this calls app.ProcessData()
            % app.ProcessData();
         end
         %NOTE: if bad input no change to existing data
      end

      % Button pushed function: SavetoOutputFileButton
      function SavetoOutputFileButtonPushed(app, event)
         if isvalid(app.M_MP)               %check for data
            pd = app.getProcParams();
            if pd.bool
               reptable = app.M_MP.getRepTable(pd.colvec,pd.blank,pd.byN);
               subtable = reptable(pd.row,:);
               [~,fname,~] = fileparts(app.DataFileNameEditField.Value);
               defname = strcat('ParameterData','_',fname);
               colname = app.DataSetEditField.Value; %get default name  
               try
                  if app.paramoutfilepath == ""
                     [filename, filepath] = uiputfile({'*.csv';'*.*'},...
                        "Open param output file",defname);
                     if filename == 0
                        return   
                     else
                        file = fullfile(filepath, filename);  %assembles path
                        app.OutputFileNameEditField.Value = filename;
                        cd(filepath);                 %change to curren dir
                        app.paramoutfilepath = file;
                        writelines(defname,file);
                        writelines("-------------------------------", ... 
                           file,"WriteMode","append");
                        writelines(colname,file,"WriteMode","append");
                        writetable(subtable,file,"WriteVariableNames",true, ...
                           "WriteMode","append");
                     end
                  else 
                     writelines(colname,app.paramoutfilepath, ...
                        'WriteMode','append');
                     %write additional data here
                     writetable(subtable,app.paramoutfilepath, ...
                        "WriteMode","append");
                  end %if
                  messagestr = strcat('Data saved to: ', ...
                     app.paramoutfilepath);
                  msgbox(messagestr);
               catch
                  errordlg('File save error, perhaps file is open?', ...
                  'File Save Error');
               end %try
            else
               errordlg('please select data to process', ...
                  'File Save Error');
            end %if
         else
            errordlg('First open and microtiter plate data file', ... 
               'File Save Error');            %no data, open MP file
         end %if
      end

      % Menu selected function: ClearAllDataMenu
      function ClearAllDataMenuSelected(app, event)
         app.ClearAll();
      end

      % Value changed function: Col_1
      function Col_1ValueChanged(app, event)
         app.ProcessData();      
      end

      % Value changed function: Col_2
      function Col_2ValueChanged(app, event)
         app.ProcessData(); 
      end

      % Value changed function: Col_3
      function Col_3ValueChanged(app, event)
         app.ProcessData(); 
      end

      % Value changed function: Col_4
      function Col_4ValueChanged(app, event)
         app.ProcessData(); 
      end

      % Value changed function: Col_5
      function Col_5ValueChanged(app, event)
         app.ProcessData(); 
      end

      % Value changed function: Col_6
      function Col_6ValueChanged(app, event)
         app.ProcessData(); 
      end

      % Value changed function: Col_7
      function Col_7ValueChanged(app, event)
         app.ProcessData(); 
      end

      % Value changed function: Col_8
      function Col_8ValueChanged(app, event)
         app.ProcessData(); 
      end

      % Value changed function: Col_9
      function Col_9ValueChanged(app, event)
         app.ProcessData(); 
      end

      % Value changed function: Col_10
      function Col_10ValueChanged(app, event)
         app.ProcessData(); 
      end

      % Value changed function: Col_11
      function Col_11ValueChanged(app, event)
         app.ProcessData(); 
      end

      % Value changed function: Col_12
      function Col_12ValueChanged(app, event)
         app.ProcessData(); 
      end

      % Selection changed function: Row
      function RowSelectionChanged(app, event)
         app.ProcessData();
      end

      % Value changed function: ControlODEditField
      function ControlODEditFieldValueChanged(app, event)
         app.ProcessData();
      end

      % Button pushed function: ColumnResetButton
      function ColumnResetButtonPushed(app, event)
         app.ClearResults();
      end

      % Value changed function: ControlColDropDown
      function ControlColDropDownValueChanged(app, event)
         app.setControlOD();
         app.ProcessData();
      end

      % Value changed function: ControlRowsDropDown_start
      function ControlRowsDropDown_startValueChanged(app, event)
         if app.checkblankrange() == true 
            app.setControlOD();
            app.ProcessData();
         end %if
      end

      % Menu selected function: SaveGrowthParameterstoSpreadsheetMenu
      function SaveGrowthParameterstoSpreadsheetMenuSelected(app, event)
         app.SavetoOutputFileButtonPushed();
      end

      % Value changed function: ControlRowsDropDown_end
      function ControlRowsDropDown_endValueChanged(app, event)
         if app.checkblankrange() == true
            app.setControlOD
            app.ProcessData();
         end %if
      end

      % Value changed function: Knob
      function KnobValueChanged(app, event)
         if isvalid(app.M_MP)
            app.plottype = app.Knob.Value;
            app.ProcessData();
         else
            app.Knob.Value = 'Growth Curves';
         end %if
      end

      % Menu selected function: ExportSelectedGrowthCurveODDataMenu
      function ExportSelectedGrowthCurveODDataMenuSelected(app, event)
         pd = app.getProcParams();
         if isvalid(app.M_MP) && pd.bool
            defname = app.DataSetEditField.Value;
            defname = strcat(defname,'_ODdata');
            [filename, filepath] = uiputfile({'*.csv';'*.*'},...
               "Open .csv data file",defname);
            if filename == 0
                  return      
            else %not cancel, save file
               file = fullfile(filepath, filename);  %assembles full path
               cd(filepath);
               crvtable = app.M_MP.getODcurveTable(pd.row,pd.colvec, ...
                  pd.blank,pd.byN);
               writetable(crvtable,file);
            end %if
         else
            msgbox('No data to export');
         end %if
      end

      % Button pushed function: AutosetButton
      function AutosetButtonPushed(app, event)
         pd = app.getProcParams();
         if pd.bool
            Rtab = app.M_MP.getRepTable(pd.colvec,pd.blank,pd.byN);
            MinODs = exp(Rtab.MinOD);
            %set selected index
            selindex = app.getSelectedIndex(MinODs);
            app.setRowButton(selindex);
            app.ProcessData();
         end
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'MATLAB App';
         app.Menu = uimenu(app.UIFigure);
         app.Menu.Text = 'Menu';
         app.OpenMPDataFileMenu = uimenu(app.Menu);
         app.OpenMPDataFileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMPDataFileMenuSelected, true);
         app.OpenMPDataFileMenu.Text = 'Open MP Data File';
         app.SaveGrowthParameterstoSpreadsheetMenu = uimenu(app.Menu);
         app.SaveGrowthParameterstoSpreadsheetMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveGrowthParameterstoSpreadsheetMenuSelected, true);
         app.SaveGrowthParameterstoSpreadsheetMenu.Text = 'Save Growth Parameters to Spreadsheet';
         app.ExportSelectedGrowthCurveODDataMenu = uimenu(app.Menu);
         app.ExportSelectedGrowthCurveODDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportSelectedGrowthCurveODDataMenuSelected, true);
         app.ExportSelectedGrowthCurveODDataMenu.Text = 'Export Selected Growth Curve OD Data ';
         app.ClearAllDataMenu = uimenu(app.Menu);
         app.ClearAllDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearAllDataMenuSelected, true);
         app.ClearAllDataMenu.Text = 'Clear All Data';
         app.ExitMenu = uimenu(app.Menu);
         app.ExitMenu.Separator = 'on';
         app.ExitMenu.Text = 'Exit';
         app.SettingsMenu = uimenu(app.UIFigure);
         app.SettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SettingsMenuSelected, true);
         app.SettingsMenu.Text = 'Settings';
         app.UIAxes = uiaxes(app.UIFigure);
         xlabel(app.UIAxes, 'Time (hours)')
         ylabel(app.UIAxes, 'Optical Density (600 nm)')
         zlabel(app.UIAxes, 'Z')
         app.ControlColDropDownLabel = uilabel(app.UIFigure);
         app.ControlColDropDownLabel.Text = 'Control Col:';
         app.ControlColDropDown = uidropdown(app.UIFigure);
         app.ControlColDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
         app.ControlColDropDown.ValueChangedFcn = createCallbackFcn(app, @ControlColDropDownValueChanged, true);
         app.ControlColDropDown.Value = '12';
         app.ControlRowsDropDownLabel = uilabel(app.UIFigure);
         app.ControlRowsDropDownLabel.Text = 'Control Row(s):';
         app.ControlRowsDropDown_start = uidropdown(app.UIFigure);
         app.ControlRowsDropDown_start.Items = {'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H'};
         app.ControlRowsDropDown_start.ValueChangedFcn = createCallbackFcn(app, @ControlRowsDropDown_startValueChanged, true);
         app.ControlRowsDropDown_start.Value = 'A';
         app.GrowthKineticsLabel = uilabel(app.UIFigure);
         app.GrowthKineticsLabel.Text = ' Growth Kinetics';
         app.GrowthRateEditFieldLabel = uilabel(app.UIFigure);
         app.GrowthRateEditFieldLabel.Text = 'Growth Rate';
         app.GrowthRateEditField = uieditfield(app.UIFigure, 'numeric');
         app.GrowthRateEditField.Editable = 'off';
         app.DoublingTimeEditFieldLabel = uilabel(app.UIFigure);
         app.DoublingTimeEditFieldLabel.Text = 'Doubling Time';
         app.DoublingTimeEditField = uieditfield(app.UIFigure, 'numeric');
         app.DoublingTimeEditField.Editable = 'off';
         app.MaxLnODEditFieldLabel = uilabel(app.UIFigure);
         app.MaxLnODEditFieldLabel.Text = 'Max Ln OD';
         app.MaxLnODEditField = uieditfield(app.UIFigure, 'numeric');
         app.MaxLnODEditField.Editable = 'off';
         app.DataFileNameEditFieldLabel = uilabel(app.UIFigure);
         app.DataFileNameEditFieldLabel.Text = 'Data File Name:';
         app.DataFileNameEditField = uieditfield(app.UIFigure, 'text');
         app.DataFileNameEditField.Editable = 'off';
         app.SavetoOutputFileButton = uibutton(app.UIFigure, 'push');
         app.SavetoOutputFileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetoOutputFileButtonPushed, true);
         app.SavetoOutputFileButton.Text = 'Save to Output File';
         app.Col_12 = uibutton(app.UIFigure, 'state');
         app.Col_12.ValueChangedFcn = createCallbackFcn(app, @Col_12ValueChanged, true);
         app.Col_12.Text = '12';
         app.Col_11 = uibutton(app.UIFigure, 'state');
         app.Col_11.ValueChangedFcn = createCallbackFcn(app, @Col_11ValueChanged, true);
         app.Col_11.Text = '11';
         app.Col_10 = uibutton(app.UIFigure, 'state');
         app.Col_10.ValueChangedFcn = createCallbackFcn(app, @Col_10ValueChanged, true);
         app.Col_10.Text = '10';
         app.Col_9 = uibutton(app.UIFigure, 'state');
         app.Col_9.ValueChangedFcn = createCallbackFcn(app, @Col_9ValueChanged, true);
         app.Col_9.Text = '9';
         app.Col_8 = uibutton(app.UIFigure, 'state');
         app.Col_8.ValueChangedFcn = createCallbackFcn(app, @Col_8ValueChanged, true);
         app.Col_8.Text = '8';
         app.Col_7 = uibutton(app.UIFigure, 'state');
         app.Col_7.ValueChangedFcn = createCallbackFcn(app, @Col_7ValueChanged, true);
         app.Col_7.Text = '7';
         app.Col_6 = uibutton(app.UIFigure, 'state');
         app.Col_6.ValueChangedFcn = createCallbackFcn(app, @Col_6ValueChanged, true);
         app.Col_6.Text = '6';
         app.Col_5 = uibutton(app.UIFigure, 'state');
         app.Col_5.ValueChangedFcn = createCallbackFcn(app, @Col_5ValueChanged, true);
         app.Col_5.Text = '5';
         app.Col_4 = uibutton(app.UIFigure, 'state');
         app.Col_4.ValueChangedFcn = createCallbackFcn(app, @Col_4ValueChanged, true);
         app.Col_4.Text = '4';
         app.Col_3 = uibutton(app.UIFigure, 'state');
         app.Col_3.ValueChangedFcn = createCallbackFcn(app, @Col_3ValueChanged, true);
         app.Col_3.Text = '3';
         app.Col_2 = uibutton(app.UIFigure, 'state');
         app.Col_2.ValueChangedFcn = createCallbackFcn(app, @Col_2ValueChanged, true);
         app.Col_2.Text = '2';
         app.Col_1 = uibutton(app.UIFigure, 'state');
         app.Col_1.ValueChangedFcn = createCallbackFcn(app, @Col_1ValueChanged, true);
         app.Col_1.Text = '1';
         app.Row = uibuttongroup(app.UIFigure);
         app.Row.SelectionChangedFcn = createCallbackFcn(app, @RowSelectionChanged, true);
         app.HButton = uitogglebutton(app.Row);
         app.HButton.Text = 'H';
         app.GButton = uitogglebutton(app.Row);
         app.GButton.Text = 'G';
         app.FButton = uitogglebutton(app.Row);
         app.FButton.Text = 'F';
         app.EButton = uitogglebutton(app.Row);
         app.EButton.Text = 'E';
         app.DButton = uitogglebutton(app.Row);
         app.DButton.Text = 'D';
         app.CButton = uitogglebutton(app.Row);
         app.CButton.Text = 'C';
         app.BButton = uitogglebutton(app.Row);
         app.BButton.Text = 'B';
         app.AButton = uitogglebutton(app.Row);
         app.AButton.Text = 'A';
         app.AButton.Value = true;
         app.InitODCtlODLabel = uilabel(app.Row);
         app.InitODCtlODLabel.Text = 'Init OD - Ctl OD';
         app.RowLabel = uilabel(app.Row);
         app.RowLabel.Text = 'Row';
         app.EditField_8 = uieditfield(app.Row, 'numeric');
         app.EditField_8.Editable = 'off';
         app.EditField_7 = uieditfield(app.Row, 'numeric');
         app.EditField_7.Editable = 'off';
         app.EditField_6 = uieditfield(app.Row, 'numeric');
         app.EditField_6.Editable = 'off';
         app.EditField_5 = uieditfield(app.Row, 'numeric');
         app.EditField_5.Editable = 'off';
         app.EditField_4 = uieditfield(app.Row, 'numeric');
         app.EditField_4.Editable = 'off';
         app.EditField_3 = uieditfield(app.Row, 'numeric');
         app.EditField_3.Editable = 'off';
         app.EditField_2 = uieditfield(app.Row, 'numeric');
         app.EditField_2.Editable = 'off';
         app.EditField_1 = uieditfield(app.Row, 'numeric');
         app.EditField_1.Editable = 'off';
         app.DataSetNameLabel = uilabel(app.UIFigure);
         app.DataSetNameLabel.Text = 'Data Set:';
         app.DataSetEditField = uieditfield(app.UIFigure, 'text');
         app.DataSetEditField.Value = 'Columns';
         app.ControlODEditFieldLabel = uilabel(app.UIFigure);
         app.ControlODEditFieldLabel.Text = 'Control OD';
         app.ControlODEditField = uieditfield(app.UIFigure, 'numeric');
         app.ControlODEditField.ValueChangedFcn = createCallbackFcn(app, @ControlODEditFieldValueChanged, true);
         app.LagTimeEditFieldLabel = uilabel(app.UIFigure);
         app.LagTimeEditFieldLabel.Text = 'Lag Time';
         app.LagTimeEditField = uieditfield(app.UIFigure, 'numeric');
         app.LagTimeEditField.Editable = 'off';
         app.ColumnResetButton = uibutton(app.UIFigure, 'push');
         app.ColumnResetButton.ButtonPushedFcn = createCallbackFcn(app, @ColumnResetButtonPushed, true);
         app.ColumnResetButton.Text = 'Column Reset';
         app.Label = uilabel(app.UIFigure);
         app.Label.Text = '>>>';
         app.ControlRowsDropDown_end = uidropdown(app.UIFigure);
         app.ControlRowsDropDown_end.Items = {'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H'};
         app.ControlRowsDropDown_end.ValueChangedFcn = createCallbackFcn(app, @ControlRowsDropDown_endValueChanged, true);
         app.ControlRowsDropDown_end.Value = 'H';
         app.OutputFileNameEditFieldLabel = uilabel(app.UIFigure);
         app.OutputFileNameEditFieldLabel.Text = 'Output File Name:';
         app.OutputFileNameEditField = uieditfield(app.UIFigure, 'text');
         app.OutputFileNameEditField.Editable = 'off';
         app.stdevLabel = uilabel(app.UIFigure);
         app.stdevLabel.Text = 'stdev';
         app.ControlODstdevEditField = uieditfield(app.UIFigure, 'numeric');
         app.Knob = uiknob(app.UIFigure, 'discrete');
         app.Knob.Items = {'Growth Curves', 'Growth Rates', 'Init OD', 'Max OD', 'Lag Time'};
         app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
         app.Knob.Value = 'Growth Curves';
         app.ColumnLabel = uilabel(app.UIFigure);
         app.ColumnLabel.Text = 'Column';
         app.AutosetButton = uibutton(app.UIFigure, 'push');
         app.AutosetButton.ButtonPushedFcn = createCallbackFcn(app, @AutosetButtonPushed, true);
         app.AutosetButton.Text = 'Autoset';
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = MicroplateGR2

         runningApp = getRunningApp(app);

         % Check for running singleton app
         if isempty(runningApp)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)
         else

            % Focus the running singleton app
            figure(runningApp.UIFigure)

            app = runningApp;
         end

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end